classdef MOI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        XAxisTab                        matlab.ui.container.Tab
        UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel_3  matlab.ui.control.Label
        XAxisMOIEditField               matlab.ui.control.NumericEditField
        XAxisMOIEditFieldLabel          matlab.ui.control.Label
        DistancefromCenterofGravitytoAxisofOscillationL2EditField  matlab.ui.control.NumericEditField
        DistancefromCenterofGravitytoAxisofOscillationL2EditFieldLabel  matlab.ui.control.Label
        WeightW2EditField               matlab.ui.control.NumericEditField
        WeightW2EditFieldLabel          matlab.ui.control.Label
        PeriodofOscillationT2EditField  matlab.ui.control.NumericEditField
        PeriodofOscillationT2EditFieldLabel  matlab.ui.control.Label
        DistancefromCenterofGravitytoAxisofOscillationL1EditField  matlab.ui.control.NumericEditField
        DistancefromCenterofGravitytoAxisofOscillationL1EditFieldLabel  matlab.ui.control.Label
        WeightW1EditField               matlab.ui.control.NumericEditField
        WeightW1EditFieldLabel          matlab.ui.control.Label
        PeriodofOscillationT1EditField  matlab.ui.control.NumericEditField
        PeriodofOscillationT1EditFieldLabel  matlab.ui.control.Label
        CalculateXAxisMOIButton         matlab.ui.control.Button
        CradleValuesLabel               matlab.ui.control.Label
        UASValuesLabel                  matlab.ui.control.Label
        YAxisTab                        matlab.ui.container.Tab
        UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel_2  matlab.ui.control.Label
        YAxisMOIEditField               matlab.ui.control.NumericEditField
        YAxisMOIEditFieldLabel          matlab.ui.control.Label
        DistancefromCenterofGravitytoAxisofOscillationL2EditField_2  matlab.ui.control.NumericEditField
        DistancefromCenterofGravitytoAxisofOscillationL2EditField_2Label  matlab.ui.control.Label
        WeightW2EditField_2             matlab.ui.control.NumericEditField
        WeightW2EditField_2Label        matlab.ui.control.Label
        PeriodofOscillationT2EditField_2  matlab.ui.control.NumericEditField
        PeriodofOscillationT2EditField_2Label  matlab.ui.control.Label
        DistancefromCenterofGravitytoAxisofOscillationL1EditField_2  matlab.ui.control.NumericEditField
        DistancefromCenterofGravitytoAxisofOscillationL1EditField_2Label  matlab.ui.control.Label
        WeightW1EditField_2             matlab.ui.control.NumericEditField
        WeightW1EditField_2Label        matlab.ui.control.Label
        PeriodofOscillationT1EditField_2  matlab.ui.control.NumericEditField
        PeriodofOscillationT1EditField_2Label  matlab.ui.control.Label
        CalculateYAxisMOIButton         matlab.ui.control.Button
        CradleValuesLabel_2             matlab.ui.control.Label
        UASValuesLabel_2                matlab.ui.control.Label
        ZAxisTab                        matlab.ui.container.Tab
        ZAxisMOIEditField               matlab.ui.control.NumericEditField
        ZAxisMOIEditFieldLabel          matlab.ui.control.Label
        WeightW2EditField_3             matlab.ui.control.NumericEditField
        WeightW2EditField_3Label        matlab.ui.control.Label
        PeriodofOscillationT2EditField_3  matlab.ui.control.NumericEditField
        PeriodofOscillationT2EditField_3Label  matlab.ui.control.Label
        LengthlEditField_3              matlab.ui.control.NumericEditField
        LengthlEditField_5Label         matlab.ui.control.Label
        DistanceBetweenFilamentsDEditField_3  matlab.ui.control.NumericEditField
        DistanceBetweenFilamentsDEditField_5Label  matlab.ui.control.Label
        WeightW1EditField_3             matlab.ui.control.NumericEditField
        WeightW1EditField_3Label        matlab.ui.control.Label
        PeriodofOscillationT1EditField_3  matlab.ui.control.NumericEditField
        PeriodofOscillationT1EditField_3Label  matlab.ui.control.Label
        CalculateZAxisMOIButton         matlab.ui.control.Button
        CradleValuesLabel_3             matlab.ui.control.Label
        UASValuesLabel_3                matlab.ui.control.Label
        UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateXAxisMOIButton
        function CalculateXAxisMOIButtonPushed(app, event)
           T1 = app.PeriodofOscillationT1EditField.Value
           W1 = app.WeightW1EditField.Value
           L1 = app.DistancefromCenterofGravitytoAxisofOscillationL1EditField.Value
           T2 = app.PeriodofOscillationT2EditField.Value
           W2 = app.WeightW2EditField.Value
           L2 = app.DistancefromCenterofGravitytoAxisofOscillationL2EditField.Value

            % X and Y axis MOI Equation
           I_Compound = ((T1*W1*L1) / (4*pi^2)) - ((T2*W2*L2) / (4*pi^2))

           app.XAxisMOIEditField.Value = I_Compound

        end

        % Button pushed function: CalculateYAxisMOIButton
        function CalculateYAxisMOIButtonPushed(app, event)
           T1 = app.PeriodofOscillationT1EditField_2.Value
           W1 = app.WeightW1EditField_2.Value
           L1 = app.DistancefromCenterofGravitytoAxisofOscillationL1EditField_2.Value
           T2 = app.PeriodofOscillationT2EditField_2.Value
           W2 = app.WeightW2EditField_2.Value
           L2 = app.DistancefromCenterofGravitytoAxisofOscillationL2EditField_2.Value

            % X and Y axis MOI Equation
           I_Compound = ((T1*W1*L1) / (4*pi^2)) - ((T2*W2*L2) / (4*pi^2))

           app.YAxisMOIEditField.Value = I_Compound
        end

        % Button pushed function: CalculateZAxisMOIButton
        function CalculateZAxisMOIButtonPushed(app, event)
           T1 = app.PeriodofOscillationT1EditField_3.Value
           W1 = app.WeightW1EditField_3.Value
            D = app.DistanceBetweenFilamentsDEditField_3.Value
           T2 = app.PeriodofOscillationT2EditField_3.Value
           W2 = app.WeightW2EditField_3.Value
            l = app.LengthlEditField_3.Value

           
           % Z Axis MOI Equation
           I_Bifilar = ((T1*W1^2*D^2) / (16*pi^2*l)) - ((T2*W2^2*D^2) / (16*pi^2*l))
           app.ZAxisMOIEditField.Value = I_Bifilar
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.XAxisTab = uitab(app.TabGroup);
            app.XAxisTab.Title = 'X-Axis';
            app.UASValuesLabel = uilabel(app.XAxisTab);
            app.UASValuesLabel.Text = 'UAS Values';
            app.CradleValuesLabel = uilabel(app.XAxisTab);
            app.CradleValuesLabel.Text = 'Cradle Values';
            app.CalculateXAxisMOIButton = uibutton(app.XAxisTab, 'push');
            app.CalculateXAxisMOIButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateXAxisMOIButtonPushed, true);
            app.CalculateXAxisMOIButton.Text = 'Calculate X-Axis MOI';
            app.PeriodofOscillationT1EditFieldLabel = uilabel(app.XAxisTab);
            app.PeriodofOscillationT1EditFieldLabel.Text = 'Period of Oscillation (T1)';
            app.PeriodofOscillationT1EditField = uieditfield(app.XAxisTab, 'numeric');
            app.WeightW1EditFieldLabel = uilabel(app.XAxisTab);
            app.WeightW1EditFieldLabel.Text = 'Weight (W1)';
            app.WeightW1EditField = uieditfield(app.XAxisTab, 'numeric');
            app.DistancefromCenterofGravitytoAxisofOscillationL1EditFieldLabel = uilabel(app.XAxisTab);
            app.DistancefromCenterofGravitytoAxisofOscillationL1EditFieldLabel.Text = {'Distance from Center of Gravity'; ' to Axis of Oscillation (L1)'};
            app.DistancefromCenterofGravitytoAxisofOscillationL1EditField = uieditfield(app.XAxisTab, 'numeric');
            app.PeriodofOscillationT2EditFieldLabel = uilabel(app.XAxisTab);
            app.PeriodofOscillationT2EditFieldLabel.Text = 'Period of Oscillation (T2)';
            app.PeriodofOscillationT2EditField = uieditfield(app.XAxisTab, 'numeric');
            app.WeightW2EditFieldLabel = uilabel(app.XAxisTab);
            app.WeightW2EditFieldLabel.Text = 'Weight (W2)';
            app.WeightW2EditField = uieditfield(app.XAxisTab, 'numeric');
            app.DistancefromCenterofGravitytoAxisofOscillationL2EditFieldLabel = uilabel(app.XAxisTab);
            app.DistancefromCenterofGravitytoAxisofOscillationL2EditFieldLabel.Text = {'Distance from Center of Gravity'; ' to Axis of Oscillation (L2)'};
            app.DistancefromCenterofGravitytoAxisofOscillationL2EditField = uieditfield(app.XAxisTab, 'numeric');
            app.XAxisMOIEditFieldLabel = uilabel(app.XAxisTab);
            app.XAxisMOIEditFieldLabel.Text = 'X-Axis MOI';
            app.XAxisMOIEditField = uieditfield(app.XAxisTab, 'numeric');
            app.UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel_3 = uilabel(app.XAxisTab);
            app.UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel_3.Text = {'Until other options are added,'; ' please use pounds and feet for units!'};
            app.YAxisTab = uitab(app.TabGroup);
            app.YAxisTab.Title = 'Y-Axis';
            app.UASValuesLabel_2 = uilabel(app.YAxisTab);
            app.UASValuesLabel_2.Text = 'UAS Values';
            app.CradleValuesLabel_2 = uilabel(app.YAxisTab);
            app.CradleValuesLabel_2.Text = 'Cradle Values';
            app.CalculateYAxisMOIButton = uibutton(app.YAxisTab, 'push');
            app.CalculateYAxisMOIButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateYAxisMOIButtonPushed, true);
            app.CalculateYAxisMOIButton.Text = 'Calculate Y-Axis MOI';
            app.PeriodofOscillationT1EditField_2Label = uilabel(app.YAxisTab);
            app.PeriodofOscillationT1EditField_2Label.Text = 'Period of Oscillation (T1)';
            app.PeriodofOscillationT1EditField_2 = uieditfield(app.YAxisTab, 'numeric');
            app.WeightW1EditField_2Label = uilabel(app.YAxisTab);
            app.WeightW1EditField_2Label.Text = 'Weight (W1)';
            app.WeightW1EditField_2 = uieditfield(app.YAxisTab, 'numeric');
            app.DistancefromCenterofGravitytoAxisofOscillationL1EditField_2Label = uilabel(app.YAxisTab);
            app.DistancefromCenterofGravitytoAxisofOscillationL1EditField_2Label.Text = {'Distance from Center of Gravity'; ' to Axis of Oscillation (L1)'};
            app.DistancefromCenterofGravitytoAxisofOscillationL1EditField_2 = uieditfield(app.YAxisTab, 'numeric');
            app.PeriodofOscillationT2EditField_2Label = uilabel(app.YAxisTab);
            app.PeriodofOscillationT2EditField_2Label.Text = 'Period of Oscillation (T2)';
            app.PeriodofOscillationT2EditField_2 = uieditfield(app.YAxisTab, 'numeric');
            app.WeightW2EditField_2Label = uilabel(app.YAxisTab);
            app.WeightW2EditField_2Label.Text = 'Weight (W2)';
            app.WeightW2EditField_2 = uieditfield(app.YAxisTab, 'numeric');
            app.DistancefromCenterofGravitytoAxisofOscillationL2EditField_2Label = uilabel(app.YAxisTab);
            app.DistancefromCenterofGravitytoAxisofOscillationL2EditField_2Label.Text = {'Distance from Center of Gravity'; ' to Axis of Oscillation (L2)'};
            app.DistancefromCenterofGravitytoAxisofOscillationL2EditField_2 = uieditfield(app.YAxisTab, 'numeric');
            app.YAxisMOIEditFieldLabel = uilabel(app.YAxisTab);
            app.YAxisMOIEditFieldLabel.Text = 'Y-Axis MOI';
            app.YAxisMOIEditField = uieditfield(app.YAxisTab, 'numeric');
            app.UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel_2 = uilabel(app.YAxisTab);
            app.UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel_2.Text = {'Until other options are added,'; ' please use pounds and feet for units!'};
            app.ZAxisTab = uitab(app.TabGroup);
            app.ZAxisTab.Title = 'Z-Axis';
            app.UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel = uilabel(app.ZAxisTab);
            app.UntilotheroptionsareaddedpleaseusepoundsandfeetforunitsLabel.Text = {'Until other options are added,'; ' please use pounds and feet for units!'};
            app.UASValuesLabel_3 = uilabel(app.ZAxisTab);
            app.UASValuesLabel_3.Text = 'UAS Values';
            app.CradleValuesLabel_3 = uilabel(app.ZAxisTab);
            app.CradleValuesLabel_3.Text = 'Cradle Values';
            app.CalculateZAxisMOIButton = uibutton(app.ZAxisTab, 'push');
            app.CalculateZAxisMOIButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateZAxisMOIButtonPushed, true);
            app.CalculateZAxisMOIButton.Text = 'Calculate Z-Axis MOI';
            app.PeriodofOscillationT1EditField_3Label = uilabel(app.ZAxisTab);
            app.PeriodofOscillationT1EditField_3Label.Text = 'Period of Oscillation (T1)';
            app.PeriodofOscillationT1EditField_3 = uieditfield(app.ZAxisTab, 'numeric');
            app.WeightW1EditField_3Label = uilabel(app.ZAxisTab);
            app.WeightW1EditField_3Label.Text = 'Weight (W1)';
            app.WeightW1EditField_3 = uieditfield(app.ZAxisTab, 'numeric');
            app.DistanceBetweenFilamentsDEditField_5Label = uilabel(app.ZAxisTab);
            app.DistanceBetweenFilamentsDEditField_5Label.Text = 'Distance Between Filaments (D)';
            app.DistanceBetweenFilamentsDEditField_3 = uieditfield(app.ZAxisTab, 'numeric');
            app.LengthlEditField_5Label = uilabel(app.ZAxisTab);
            app.LengthlEditField_5Label.Text = 'Length (l)';
            app.LengthlEditField_3 = uieditfield(app.ZAxisTab, 'numeric');
            app.PeriodofOscillationT2EditField_3Label = uilabel(app.ZAxisTab);
            app.PeriodofOscillationT2EditField_3Label.Text = 'Period of Oscillation (T2)';
            app.PeriodofOscillationT2EditField_3 = uieditfield(app.ZAxisTab, 'numeric');
            app.WeightW2EditField_3Label = uilabel(app.ZAxisTab);
            app.WeightW2EditField_3Label.Text = 'Weight (W2)';
            app.WeightW2EditField_3 = uieditfield(app.ZAxisTab, 'numeric');
            app.ZAxisMOIEditFieldLabel = uilabel(app.ZAxisTab);
            app.ZAxisMOIEditFieldLabel.Text = 'Z-Axis MOI';
            app.ZAxisMOIEditField = uieditfield(app.ZAxisTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end